classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PatientMonitorUIFigure   matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        COMPortDropDownLabel     matlab.ui.control.Label
        COMPortDropDown          matlab.ui.control.DropDown
        StartButton              matlab.ui.control.Button
        StopButton               matlab.ui.control.Button
        RefreshBTOptionsButton   matlab.ui.control.Button
        CommMethodButtonGroup    matlab.ui.container.ButtonGroup
        USBButton                matlab.ui.control.RadioButton
        BTButton                 matlab.ui.control.RadioButton
        BTDeviceLabel            matlab.ui.control.Label
        BTDropDown               matlab.ui.control.DropDown
        RefreshUSBOptionsButton  matlab.ui.control.Button
        RRMonitorLabel           matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        Loader                   matlab.ui.control.Image
        PauseButton              matlab.ui.control.Button
        RightPanel               matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        SaveCSVFileButton        matlab.ui.control.Button
        ShowDetectsCheckBox      matlab.ui.control.CheckBox
        ScrollCheckBox           matlab.ui.control.CheckBox
        UIAxes3                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        stop = false;
        data = [];
        lastSave = 0; % Description
        datasets = 5; % Description
        configFile = 'config.json';
        dumpFile = 'dmp.csv';% Description
        configData = []; % Description
        paused = false; % Description
   end
    
    methods (Access = private)
        
        function [] = fixDataOnReset(app,index)
            resetAxes(app);
            app.data(:,1:index-1) = [];
            if (isfile(app.dumpFile))
               delete(app.dumpFile);
           end
        end
        
        function [] = dumpContents(app,index)
           if(isfile(app.dumpFile))
               dlmwrite(app.dumpFile ,app.data(:,1:index).' ,'-append');

           else
               dlmwrite(app.dumpFile ,app.data(:,1:index).');
           end
            app.lastSave = app.lastSave + index;
            app.data(:,1:index) = [];
            
        end
        
        function closePopup(app,f)
            delete(f);
        end
        
        function temperature = voltageToTemp(app,voltage)
        if(~app.configData.showTemp)
            temperature = voltage;
            return
        end
          temperature = -10.8874 + 10.0548*voltage + 0.1096*voltage*voltage; %obtained using polyfit;
        end
        
        function []  = plotFFT(app,index)
            cla(app.UIAxes3);

            Ts = (app.data(end,2) - app.data(end,1))/1000;
            
            Fs = 1/Ts;
            
            L = index;
            Y = fft(app.data(2,1:index));
            f = Fs*(0:floor(L/2))/L;
            P2 = abs(Y/L);
            P1 = P2(1:floor(L/2)+1);
            P1(2:end-1) = 2*P1(2:end-1);
            
            [val,index] = max(P1(2:end));
            txt = strcat('Most prominent = ',num2str(f(index+1)*60));
            hold(app.UIAxes3,'on');
            plot(app.UIAxes3,f(2:end)*60,P1(2:end));
            plot(app.UIAxes3,f(index+1)*60,val,'r*');
            text(app.UIAxes3,f(index+1)*60,val,txt);
            hold(app.UIAxes3,'off');
           % xlim([0 200]);
            
        end
        
        function [] = resetAxes(app)
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           directory = fileparts(mfilename('fullpath'));
           app.dumpFile = fullfile(directory,app.dumpFile);
           configFileName = fullfile(directory,app.configFile);
           if (isfile(configFileName))
                app.configData = jsondecode(fileread(configFileName));
           end
           cfgData = app.configData;

           app.COMPortDropDown.Items = seriallist();
           try
                temp = instrhwinfo('Bluetooth');
                temp = temp.RemoteNames;
                temp = temp(startsWith(temp,cfgData.BTPrefix));
                app.BTDropDown.Items = temp;
           catch
                uialert(app.PatientMonitorUIFigure,'Could not search for bluetooth devices. Please ensure BT is turned on.','Error','Icon','error');

           end
          
           app.StopButton.Visible = false;
           app.PauseButton.Visible = false;
           
           if (app.configData.showTemp)
               app.UIAxes.YLabel.String= 'Temperature (approx.)';
           else
              app.UIAxes.YLabel.String= 'Voltage';

           end

           app.Loader.Visible = false;
           if (isfile(app.dumpFile))
               delete(app.dumpFile);
           end
          
           delete(instrfind);

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PatientMonitorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {611, 611};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {189, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if (isfile(app.dumpFile))
               delete(app.dumpFile);
            end
            app.Loader.Visible = true;

        
            app.StartButton.Visible = false;
            drawnow;

            resetAxes(app);
        
            unfilt = animatedline(app.UIAxes,'Color','r');
            filt = animatedline(app.UIAxes,'Color','b');
            dets = animatedline(app.UIAxes,'Color','m','Marker','*','Tag','detects');
            dets.LineStyle = 'none';
            RR = animatedline(app.UIAxes2,'Color','g');

            app.stop = false;
            s= [];
            
            if(app.USBButton.Value)
                s = serial(app.COMPortDropDown.Value); % change the COM Port number as needed
                s.BaudRate = 115200;
            else
                s = Bluetooth(app.BTDropDown.Value,1,'Timeout',app.configData.timeout);
            end
        
            drawnow;
        
            s.InputBufferSize = 1024; % read only one byte every time
            s.Terminator = 'CR/LF';
            
            try
                fopen(s);
            catch err
                fclose(instrfind);
                app.StartButton.Visible = true;
                app.Loader.Visible = false;
                uialert(app.PatientMonitorUIFigure,'Could not initiate connection. Please try again.','Error','Icon','error');
                return;
               % error('Make sure you select the correct COM Port where the Arduino is connected.');
            end
            
            app.Loader.Visible = false;

            app.StopButton.Visible = true;
            app.PauseButton.Visible = true;


            app.data = NaN(app.datasets,1000);
            t = zeros(1,100);

            Fs=125;
            
            Ts = (1/Fs)/4; % Sampling time (s)
            i = 0;
            tic; % Start timer
            
            
            tic;
            prevDmp = toc;
            
            dmpInterval = app.configData.dumpInterval;
            while (1)
                i = i + 1;
                %% Read buffer data
                  
                
                       lastwarn(''); %reset
                       lines = fscanf(s);
                       [warnMsg, warnId] = lastwarn;
                       
                       %detect if bt disconnects
                       if ~isempty(warnMsg)
                           uialert(app.PatientMonitorUIFigure,'Connection lost: The device was unplugged or powered off','Error','Icon','error');
                           app.stop = true;
                           break;
                       end
                       
                       
                       p =  sscanf(lines,'%f %f %lu %f %f');
                    
                    
                       for j = 1:app.datasets
                            if length(p) ==app.datasets
                                app.data(j,i) = p(j);
                            elseif i>1
                               app.data(j,i) = app.data(j,i-1);
                            else
                                app.data(j,i)=0;
                            end
                       end
                t(i) = app.data(end,i)/1000;
                
                %convert to temperature 


                t_0 = toc;
                t_1 = t_0;


                while((t_1-t_0)<Ts)
                    t_1=toc;
                end
                
                if((t_0-prevDmp) > dmpInterval)
                    prevDmp = t_0;
                    plotFFT(app,i);
                    dumpContents(app,i-1);
                    
                    i = 1;
                end
                
                
                %% Plot live data
                if i > 1
                    
                    if(t(i)<t(i-1))%%happens on reset if using BT
                        uialert(app.PatientMonitorUIFigure,'Logging was reset by the device: Restarting logging.','Warning','Icon','warning');
                        fixDataOnReset(app,i);
                        temp  = t(i);
                        i = 1;
                        t = zeros(1,100);
                        t(1) = temp;
                        unfilt = animatedline(app.UIAxes,'Color','r');
                        filt = animatedline(app.UIAxes,'Color','b');
                        dets = animatedline(app.UIAxes,'Color','m','Marker','*');
                        dets.LineStyle = 'none';
                        RR = animatedline(app.UIAxes2,'Color','g','Tag','detects');
                        continue;
                    end


                    addpoints(unfilt,t(i),voltageToTemp(app,app.data(1,i)));
                    addpoints(filt,t(i),voltageToTemp(app,app.data(2,i)));
                    addpoints(RR,t(i),app.data(4,i));

                    
                    %line(app.UIAxes,[t(i-1) t(i)],[app.data(1,i-1) app.data(1,i)],'Color','red')
                    %line(app.UIAxes,[t(i-1) t(i)],[app.data(2,i-1) app.data(2,i)],'Color','blue')
                    
                    
                    %line(app.UIAxes2,[t(i-1) t(i)],[app.data(4,i-1) app.data(4,i)],'Color','green')
            
                   
                    if (app.data(3,max(i-1,1))<app.data(3,i))
                        if(app.ShowDetectsCheckBox.Value==1)
                              dets.Marker= '*';

                     %       line(app.UIAxes,[t(i) t(i)],[app.data(2,i) app.data(2,i)],'Color','magenta','Marker','*','Tag','detects')
                             addpoints(dets, app.data(3,i)/1000, voltageToTemp(app,app.data(2,i)));

                        else
                             dets.Marker= 'none';

                      %      line(app.UIAxes,[t(i) t(i)],[app.data(2,i) app.data(2,i)],'Color','magenta','Marker','none','Tag','detects')
    
                        end
                    end
                    %pause(eps);
                    
                    if(app.ScrollCheckBox.Value)
                        xlim(app.UIAxes,[max(t(i)-20,t(1)),t(i)]);
                        xlim(app.UIAxes2,[max(t(i)-20,t(1)),t(i)]);
                        ylim(app.UIAxes,'auto');
                        ylim(app.UIAxes2,'auto');

                    end
                    drawnow limitrate;
                    
                end
                
                while (app.paused)
                    app.PauseButton.Visible = true;
                    pause(.00001);
                end
                
                
                if (app.stop)
                    break;
                end
            
            end
            app.data= app.data(:,1:min(i,length(app.data(:,1)))); 
            app.StartButton.Visible = true;
            app.StopButton.Visible = false;
            app.PauseButton.Visible = false;

            fclose(s);
            delete (s);

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stop = true;
            app.paused = false;
            app.PauseButton.Text = 'Pause';

        end

        % Button pushed function: SaveCSVFileButton
        function SaveCSVFileButtonPushed(app, event)
            filt = {'*.csv';'*.*'};
            [file,path,~] = uiputfile(filt,'Save new Log File','new');
            app.PatientMonitorUIFigure.Visible = 'off';
            app.PatientMonitorUIFigure.Visible = 'on';
            filename = fullfile(path,file);
            
            if isequal(file,0) || isequal(path,0)
                 return;
            end
                        
            if(app.lastSave > 0)
               dlmwrite(app.dumpFile ,app.data.','-append');
            else
               dlmwrite(app.dumpFile ,app.data.');
            end
            
            status = copyfile(app.dumpFile , filename);
            
            delete(app.dumpFile);
        end

        % Value changed function: ShowDetectsCheckBox
        function ShowDetectsCheckBoxValueChanged(app, event)
            value = app.ShowDetectsCheckBox.Value;
            dataObjs = app.UIAxes.Children;
            detMarkers = findobj(dataObjs,'Tag','detects');
            if(value == 1)
                set(detMarkers,'Marker','*');
            else
                set(detMarkers,'Marker','none');
            end
            
        end

        % Button pushed function: RefreshBTOptionsButton
        function RefreshBTOptionsButtonPushed(app, event)
        try
            temp = instrhwinfo('Bluetooth');
            app.BTDropDown.Items = temp.RemoteNames;
        catch
            uialert(app.PatientMonitorUIFigure,'Could not search for bluetooth devices. Please ensure BT is turned on.','Error','Icon','error');

        end
        end

        % Value changed function: ScrollCheckBox
        function ScrollCheckBoxValueChanged(app, event)
            value = app.ScrollCheckBox.Value;
            if(~value)
                axis (app.UIAxes,'auto');
                axis (app.UIAxes2,'auto');
            end
        end

        % Button pushed function: RefreshUSBOptionsButton
        function RefreshUSBOptionsButtonPushed(app, event)
           app.COMPortDropDown.Items = seriallist();

        end

        % Close request function: PatientMonitorUIFigure
        function PatientMonitorUIFigureCloseRequest(app, event)
             if(isfile(app.dumpFile))
                delete(app.dumpFile);
            end
            delete(app)
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.stop = false;
            if(app.paused)
                app.PauseButton.Text = 'Pause'
            else
                app.PauseButton.Text = 'Resume'
            end
            app.paused = ~app.paused;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PatientMonitorUIFigure = uifigure('Visible', 'off');
            app.PatientMonitorUIFigure.Name = 'Patient Monitor';
            app.PatientMonitorUIFigure.CloseRequestFcn = createCallbackFcn(app, @PatientMonitorUIFigureCloseRequest, true);
            app.PatientMonitorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.PatientMonitorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.COMPortDropDownLabel = uilabel(app.LeftPanel);
            app.COMPortDropDownLabel.Text = 'COM Port';
            app.COMPortDropDown = uidropdown(app.LeftPanel);
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.LeftPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RefreshBTOptionsButton = uibutton(app.LeftPanel, 'push');
            app.RefreshBTOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshBTOptionsButtonPushed, true);
            app.RefreshBTOptionsButton.Text = 'Refresh BT Options';
            app.CommMethodButtonGroup = uibuttongroup(app.LeftPanel);
            app.CommMethodButtonGroup.Title = 'Comm Method';
            app.USBButton = uiradiobutton(app.CommMethodButtonGroup);
            app.USBButton.Text = 'USB';
            app.USBButton.Value = true;
            app.BTButton = uiradiobutton(app.CommMethodButtonGroup);
            app.BTButton.Text = 'BT';
            app.BTDeviceLabel = uilabel(app.LeftPanel);
            app.BTDeviceLabel.Text = 'BT Device';
            app.BTDropDown = uidropdown(app.LeftPanel);
            app.RefreshUSBOptionsButton = uibutton(app.LeftPanel, 'push');
            app.RefreshUSBOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshUSBOptionsButtonPushed, true);
            app.RefreshUSBOptionsButton.Text = 'Refresh USB Options';
            app.RRMonitorLabel = uilabel(app.LeftPanel);
            app.RRMonitorLabel.Text = {'RR'; 'Monitor'; ''};
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'manandlungs.svg';
            app.Loader = uiimage(app.LeftPanel);
            app.Loader.Tooltip = {'Connceting to the device...'};
            app.Loader.ImageSource = 'g2.gif';
            app.PauseButton = uibutton(app.LeftPanel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Breath Waveforms')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Temperature (approx.)')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'RR Waveform')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Calculated Rate')
            app.SaveCSVFileButton = uibutton(app.RightPanel, 'push');
            app.SaveCSVFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCSVFileButtonPushed, true);
            app.SaveCSVFileButton.Text = ' Save CSV File';
            app.ShowDetectsCheckBox = uicheckbox(app.RightPanel);
            app.ShowDetectsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowDetectsCheckBoxValueChanged, true);
            app.ShowDetectsCheckBox.Text = 'Show Detects';
            app.ShowDetectsCheckBox.Value = true;
            app.ScrollCheckBox = uicheckbox(app.RightPanel);
            app.ScrollCheckBox.ValueChangedFcn = createCallbackFcn(app, @ScrollCheckBoxValueChanged, true);
            app.ScrollCheckBox.Text = 'Scroll';
            app.ScrollCheckBox.Value = true;
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'FFT Plot')
            xlabel(app.UIAxes3, 'Frequency (BPM)')
            ylabel(app.UIAxes3, 'Magnitude')
            % Show the figure after all components are created
            app.PatientMonitorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PatientMonitorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PatientMonitorUIFigure)
        end
    end
end